classdef func < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        BuildButton    matlab.ui.control.Button
        ysinxLabel     matlab.ui.control.Label
        yex2Label      matlab.ui.control.Label
        ClearButton    matlab.ui.control.Button
        BuildButton_2  matlab.ui.control.Button
        ClearButton_2  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        f1
        f
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
            hold(app.UIAxes,"on");
            grid(app.UIAxes,"on");
            x1 = 0:0.1:4*pi;
            app.f1 = plot(app.UIAxes,x1,sin(x1));
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            hold(app.UIAxes,"on");
            grid(app.UIAxes,"on");
            x1 = 0:0.1:4*pi;
            app.f1 = plot(app.UIAxes,x1,sin(x1),'w');
        end

        % Button pushed function: BuildButton_2
        function BuildButton_2Pushed(app, event)
            hold(app.UIAxes,"on");
            grid(app.UIAxes,"on");
            x = -2:0.2:2;
            app.f = plot(app.UIAxes,x,exp(-x.^2));
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            hold(app.UIAxes,"on");
            grid(app.UIAxes,"on");
            x = -2:0.2:2;
            app.f = plot(app.UIAxes,x,exp(-x.^2),'w');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BuildButton = uibutton(app.UIFigure, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = 'Build';
            app.ysinxLabel = uilabel(app.UIFigure);
            app.ysinxLabel.Text = 'y = sin(x)';
            app.yex2Label = uilabel(app.UIFigure);
            app.yex2Label.Text = 'y = e^(-x^2)';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.BuildButton_2 = uibutton(app.UIFigure, 'push');
            app.BuildButton_2.ButtonPushedFcn = createCallbackFcn(app, @BuildButton_2Pushed, true);
            app.BuildButton_2.Text = 'Build';
            app.ClearButton_2 = uibutton(app.UIFigure, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = func

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end