classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        xEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.EditField
        yEditFieldLabel  matlab.ui.control.Label
        yEditField       matlab.ui.control.EditField
        zEditFieldLabel  matlab.ui.control.Label
        zEditField       matlab.ui.control.EditField
        BuildButton      matlab.ui.control.Button
        Label            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        tLabel           matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        CheckBox         matlab.ui.control.CheckBox
        tLabel_2         matlab.ui.control.Label
        tLabel_3         matlab.ui.control.Label
        nLabel           matlab.ui.control.Label
        bLabel           matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        Label_6          matlab.ui.control.Label
        Label_7          matlab.ui.control.Label
        aEditField       matlab.ui.control.EditField
        bEditField       matlab.ui.control.EditField
        cEditField       matlab.ui.control.EditField
        tEditField       matlab.ui.control.EditField
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
            x = str2sym(app.xEditField.Value);
            y = str2sym(app.yEditField.Value);
            z = str2sym(app.zEditField.Value);
            a = double(str2sym(app.aEditField.Value));
            b = double(str2sym(app.bEditField.Value));
            c = double(str2sym(app.cEditField.Value));
            t = a:c:b;
            plot3(app.UIAxes,subs(x,t),subs(y,t),subs(z,t),'r');
            grid(app.UIAxes,"on");
            if app.CheckBox.Value
                f = double(str2sym(app.tEditField.Value));
                xf = double(subs(x,f));
                yf = double(subs(y,f));
                zf = double(subs(z,f));
                dx = double(subs(diff(x,1),f));
                dy = double(subs(diff(y,1),f));
                dz = double(subs(diff(z,1),f));
                l = sqrt(dx^2+dy^2+dz^2);
                line(app.UIAxes,[xf,xf+dx/l],[yf,yf+dy/l],[zf,zf+dz/l]);
                if ~l
                    l = 1;
                end
                app.Label_5.Text = "("+num2str(dx/l)+";"+num2str(dy/l)+";"+num2str(dz/l)+")";
                dxx = double(subs(diff(x,2),f));
                dyy = double(subs(diff(y,2),f));
                dzz = double(subs(diff(z,2),f));
                bx = dy * dzz - dz * dyy;
                by = -(dx * dzz - dz * dxx);
                bz = dx * dyy - dy * dxx;
                l = sqrt(bx^2+by^2+bz^2);
                line(app.UIAxes,[xf,xf+bx/l],[yf,yf+by/l],[zf,zf+bz/l]);
                if ~l
                    l = 1;
                end
                app.Label_7.Text = "("+num2str(bx/l)+";"+num2str(by/l)+";"+num2str(bz/l)+")";
                nx = dy * bz - dz * by;
                ny = -(dx * bz - dz * bx);
                nz = dx * by - dy * bx;
                l = sqrt(nx^2+ny^2+nz^2);
                line(app.UIAxes,[xf,xf+nx/l],[yf,yf+ny/l],[zf,zf+nz/l]);
                if ~l
                    l = 1;
                end
                app.Label_6.Text = "("+num2str(nx/l)+";"+num2str(ny/l)+";"+num2str(nz/l)+")";
            else
                app.Label_5.Text = "";
                app.Label_6.Text = "";
                app.Label_7.Text = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            app.BuildButton = uibutton(app.UIFigure, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = 'Build';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Параметрическое '; 'уравнение кривой'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Диапозон изменения параметра';
            app.tLabel = uilabel(app.UIFigure);
            app.tLabel.Text = '<=  t  <=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Шаг изменения параметра';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Репер Френе в точке ';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.tLabel_2 = uilabel(app.UIFigure);
            app.tLabel_2.Text = 't  =';
            app.tLabel_3 = uilabel(app.UIFigure);
            app.tLabel_3.Text = 't̅:';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n̅:';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b̅:';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.cEditField = uieditfield(app.UIFigure, 'text');
            app.tEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end