classdef check_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        sinxCheckBox  matlab.ui.control.CheckBox
        cosxCheckBox  matlab.ui.control.CheckBox
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        f1
        f2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: sinxCheckBox
        function sinxCheckBoxValueChanged(app, event)
            value = app.sinxCheckBox.Value;
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
            if value
                x = -5:0.05:5;
                app.f1 = plot(app.UIAxes,x,sin(x));
            else
                delete(app.f1);
            end
        end

        % Value changed function: cosxCheckBox
        function cosxCheckBoxValueChanged(app, event)
            value = app.cosxCheckBox.Value;
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
            if value
                x = -5:0.05:5;
                app.f2 = plot(app.UIAxes,x,cos(x));
            else
                delete(app.f2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sinxCheckBox = uicheckbox(app.UIFigure);
            app.sinxCheckBox.ValueChangedFcn = createCallbackFcn(app, @sinxCheckBoxValueChanged, true);
            app.sinxCheckBox.Text = 'sin(x)';
            app.cosxCheckBox = uicheckbox(app.UIFigure);
            app.cosxCheckBox.ValueChangedFcn = createCallbackFcn(app, @cosxCheckBoxValueChanged, true);
            app.cosxCheckBox.Text = 'cos(x)';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = check_box

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end